classdef sphereApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
%           Definimos los ejes en donde graficaremos los datos de
%           entrada
            axis(app.UIAxes, [-2 2 -2 2]);
            grid(app.UIAxes, 'on');
            
%           Graficamos los datos de entrada usando el método ginput
            fhv = app.UIFigure.HandleVisibility;        
            app.UIFigure.HandleVisibility = 'callback'; % Callback que actualiza app.UIFigure  
            set(0, 'CurrentFigure', app.UIFigure)       
            
            [x,y] = ginput;                        
            
            app.UIFigure.HandleVisibility = fhv; 
            plot(x, y, 'Parent', app.UIAxes)
           
%           Definimos los ejes en donde graficaremos la esfera y la
%           proyección estereográfica de los segmentos formados por los
%           datos de entrada
            z = x + 1i*y;
            zFull = f_unir_puntos_interfaz(z); % Retorna el total de puntos de cada segmento
            graphProyectionInRiemmanSphereGUI(zFull, 'b', app.UIAxes_2); % Proyección estereográfica
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Complex plane')
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Riemann sphere')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sphereApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end